classdef GazepointDemo_menu < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MainMenuUIFigure         matlab.ui.Figure
        DigitSpanButton          matlab.ui.control.Button
        LoomingButton            matlab.ui.control.Button
        SoundsButton             matlab.ui.control.Button
        TwoTonesButton           matlab.ui.control.Button
        SelectanexperimentLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: DigitSpanButton, LoomingButton, 
        % SoundsButton, TwoTonesButton
        function LaunchApp(app, event)
            
            
            switch event.Source.Text
                case 'Digit Span'
                    app_filename = 'DigitSpan_app';
                case 'Looming'
                    app_filename = 'Looming_app';
                case 'Sounds'
                    app_filename = 'Sounds_app';
                case 'Two Tones'
                    app_filename = 'TwoTones_app';
            end
            
            eval(app_filename);
            
            delete(app.MainMenuUIFigure)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MainMenuUIFigure = uifigure('Visible', 'off');
            app.MainMenuUIFigure.Name = 'Main Menu';
            app.DigitSpanButton = uibutton(app.MainMenuUIFigure, 'push');
            app.DigitSpanButton.ButtonPushedFcn = createCallbackFcn(app, @LaunchApp, true);
            app.DigitSpanButton.Text = 'Digit Span';
            app.LoomingButton = uibutton(app.MainMenuUIFigure, 'push');
            app.LoomingButton.ButtonPushedFcn = createCallbackFcn(app, @LaunchApp, true);
            app.LoomingButton.Text = 'Looming';
            app.SoundsButton = uibutton(app.MainMenuUIFigure, 'push');
            app.SoundsButton.ButtonPushedFcn = createCallbackFcn(app, @LaunchApp, true);
            app.SoundsButton.Text = 'Sounds';
            app.TwoTonesButton = uibutton(app.MainMenuUIFigure, 'push');
            app.TwoTonesButton.ButtonPushedFcn = createCallbackFcn(app, @LaunchApp, true);
            app.TwoTonesButton.Text = 'Two Tones';
            app.SelectanexperimentLabel = uilabel(app.MainMenuUIFigure);
            app.SelectanexperimentLabel.Text = 'Select an experiment:';
            % Show the figure after all components are created
            app.MainMenuUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GazepointDemo_menu

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MainMenuUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MainMenuUIFigure)
        end
    end
end