classdef Sounds_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        PupilAxis                     matlab.ui.control.UIAxes
        SubjectIDEditFieldLabel       matlab.ui.control.Label
        SubjectIDEditField            matlab.ui.control.EditField
        ConnectButton                 matlab.ui.control.Button
        StartExperimentButton         matlab.ui.control.Button
        DisconnectButton              matlab.ui.control.Button
        StopExperimentButton          matlab.ui.control.Button
        Configuration_Button          matlab.ui.control.StateButton
        Configuration_Panel           matlab.ui.container.Panel
        OutputFolderButton            matlab.ui.control.Button
        OutputFolderEditField         matlab.ui.control.EditField
        BaselineEditFieldLabel_2      matlab.ui.control.Label
        Event1_Dur_EditField          matlab.ui.control.NumericEditField
        PostAudioLabel_2              matlab.ui.control.Label
        Event2_Dur_EditField          matlab.ui.control.NumericEditField
        DurationLabel                 matlab.ui.control.Label
        EarButtonGroup                matlab.ui.container.ButtonGroup
        LeftEarButton                 matlab.ui.control.ToggleButton
        RightEarButton                matlab.ui.control.ToggleButton
        BothEarsButton                matlab.ui.control.ToggleButton
        AudioFileDropDownLabel        matlab.ui.control.Label
        AudioFileDropDown             matlab.ui.control.DropDown
        Event1_L_EditField            matlab.ui.control.NumericEditField
        Event2_L_EditField            matlab.ui.control.NumericEditField
        Event3_L_EditField            matlab.ui.control.NumericEditField
        Event1_R_EditField            matlab.ui.control.NumericEditField
        Event2_R_EditField            matlab.ui.control.NumericEditField
        Event3_R_EditField            matlab.ui.control.NumericEditField
        L_PupilLabel                  matlab.ui.control.Label
        R_PupilLabel                  matlab.ui.control.Label
        BaselineLampLabel             matlab.ui.control.Label
        Event1_Lamp                   matlab.ui.control.Lamp
        AudioLabel                    matlab.ui.control.Label
        Event2_Lamp                   matlab.ui.control.Lamp
        PostAudioLabel                matlab.ui.control.Label
        Event3_Lamp                   matlab.ui.control.Lamp
        RefreshButton                 matlab.ui.control.Button
        ExperimentNameEditFieldLabel  matlab.ui.control.Label
        ExperimentNameEditField       matlab.ui.control.EditField
    end


    properties (Access = public)
        % These dummy properties are required to run commands here; change
        % directory to parent directory and add necessary paths before
        % creating elements
        dummy1 = cd(fileparts(mfilename('fullpath')))
        dummy2 = addpath('Icons');    
        parent_directory = pwd;     % Note: dummy1 ~= parent_directory
        
        % Marker properties
        marker
        marker_labels = {'BASELINE','AUDIO','POST_AUDIO','STOP_RECORDING'};
        marker_colors = {[0 0 0],[0.8 0.4 0.6],[0.4 0.8 0.8],[0 0 0]};
        marker_y_lower = 0;
        marker_y_upper = 50;
        
        % Lamp properties
        lamp_on_color = [0 0.8 0];
        lamp_off_color = [0.65 0.65 0.65];
        
        % Plot properties
        plot_delay = 0.1; % Delay (in seconds) before updating graphics
        left_pupil_plot
        right_pupil_plot
        
        % Other properties
        experiment_name
        session2_client
        header
    end
    
    methods (Access = private)
        
        function ReloadAudioFiles(app)
            % Checks the Stimuli/Sounds folder for added/removed sound
            % audio files
            audio_files = dir(fullfile(app.parent_directory,'Stimuli','Sounds'));
            app.AudioFileDropDown.Items = {audio_files(~cellfun(@isempty,regexp({audio_files.name},'.wav|.mp3'))).name}; %add files with compatible extensions (mp3 or wav) to drop-down
        end
        
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Addpath to sub-dirs from the parent dir of this file
            addpath(genpath(app.parent_directory));         
            
            % Detect audio files in the Stimuli/Sounds sub-folder and add
            % to drop-down
            ReloadAudioFiles(app);
            
            % Initialize left and right pupil plots
            app = GUI_InitializePupilPlots(app);
            
            % Initialize marker structure (including the line handles and labels)
            app = GUI_InitializeMarkers(app);
            
            % Tooltips
            app.ExperimentNameEditField.Tooltip = GUI_Tooltips(1);
            app.ExperimentNameEditFieldLabel.Tooltip = GUI_Tooltips(1);
            app.SubjectIDEditField.Tooltip = GUI_Tooltips(2);
            app.SubjectIDEditFieldLabel.Tooltip = GUI_Tooltips(2);
            app.ConnectButton.Tooltip = GUI_Tooltips(3);
            app.DisconnectButton.Tooltip = GUI_Tooltips(4);
            app.StartExperimentButton.Tooltip = GUI_Tooltips(5);
            app.StopExperimentButton.Tooltip = GUI_Tooltips(6);
            app.EarButtonGroup.Tooltip = GUI_Tooltips(7);
            app.RefreshButton.Tooltip = GUI_Tooltips(8);
            
        end

        % Button pushed function: ConnectButton
        function SetUpConnections(app, event)
            
            % Establish Connections Sequence
            app = GUI_EstablishConnectionsSequence(app, 'Experiment_Sounds', 'ProgressDialog', 'on');
            
        end

        % Button pushed function: StartExperimentButton
        function StartExperiment(app, event)
            % Update Pushbutton States
            app.StartExperimentButton.Enable = 'off';
            app.DisconnectButton.Enable = 'off';
            app.StopExperimentButton.Enable = 'on'; 
                      
            % Retrieve user input from fields and store in data structure p
            p.Sub = app.SubjectIDEditField.Value;
            p.Event1Dur = num2str(app.Event1_Dur_EditField.Value);
            p.Event2Dur = num2str(app.Event2_Dur_EditField.Value);
            p.Audio = app.AudioFileDropDown.Value;
            p.Exp = app.ExperimentNameEditField.Value;
            p.Ear = upper(app.EarButtonGroup.SelectedObject.Text(1:end-4));     % 3 opts: LEFT, RIGHT, or BOTH
            
            % Create file name and write header
            [app, outputFullPathName] = GUI_DefineOutputFullPathName(app,p,{'Sub','Ear','Exp'});     % only includes these fields in filename            
            fileID = fopen(outputFullPathName,'w');
            fprintf(fileID,['DATATYPE\t' repmat('%s\t',1,length(app.header)) '\n'],app.header{:});
                           
            % Insert <UPPER> before any uppercase letters in the audio
            % filename. Sending the message to gazepoint automatically
            % converts the filename to uppercase. This enables
            % distinguishing filenames
            p.Audio = regexprep(p.Audio,'([A-Z])','<upper>$1'); %the trick - use a token
            
            % Replace any underscores or dashes with <UNDERSCORE> or 
            % <DASH> to avoid confusing filename underscores with 
            % parsing underscores
            p.Audio = regexprep(p.Audio,'_','<underscore>');
            p.Audio = regexprep(p.Audio,'-','<dash>');
            
            % Sends message to session 1 client to start experiment
            start_msg_string_pairs = GUI_CreateStringPairs(p,fieldnames(p)'); % all fields included in the start message string
            start_message = ['START_' start_msg_string_pairs];
            SendMsgToGP3(app.session2_client,start_message);
            
            % Starts collecting data from GP3 server
            fprintf(app.session2_client, '<SET ID="ENABLE_SEND_DATA" STATE="1" />');
            pause(0.1);     % gives a chance for session2_client buffer to start receiving data
        
            % Reset Plots and axes XLim
            hold(app.PupilAxis,'on');
            app = GUI_ClearPlots(app);
            audio_info = audioinfo(app.AudioFileDropDown.Value);
            app.PupilAxis.XLim(2) = sum([app.Event1_Dur_EditField.Value, audio_info.Duration, app.Event2_Dur_EditField.Value])+2;
            
            % Reset Fields that display pupil means
            [app.Event1_L_EditField.Value, app.Event2_L_EditField.Value, app.Event3_L_EditField.Value, ...
                app.Event1_R_EditField.Value, app.Event2_R_EditField.Value, app.Event3_R_EditField.Value] = deal(0);
            
            % Define counter variables and data containers
            previous_user_data = '';
            msg_count = 0;
            smp_count = 0;
            last_timestamp_zeroed = 0;
            marker_count = 0;
            x = [];
            y_left = [];
            y_right = [];

            % Wait for first marker label before proceeding
            fprintf('\n');  
            Client_WaitForMessage(app.session2_client, app.marker_labels{1}, 'TimeOutDuration', 10);
            
            timed_out_state = 0;
            while  timed_out_state == 0
                
                % Wait up to 10s for buffer to receive data; if no data is
                % streamed after 10s, automatically times out
                start_time = tic;          
                while app.session2_client.BytesAvailable == 0 && timed_out_state == 0
                    % Exits all while loops after 10s of no data in the
                    % buffer
                    if toc(start_time) > 10
                        timed_out_state = 1;
                    end
                end
                
                if timed_out_state == 1
                    GUI_CommandWindowMessages(1);
                    break
                end
                
                % Scan data from buffer and parse the xml format
                [dataSplit, current_user_data] = RetrieveDataFromBuffer(app.session2_client);
                
                if regexp(dataSplit{1},'<REC','once')
                    smp_count = smp_count+1;       
                    value = dataSplit(2:2:length(dataSplit));       % Saves every second element of the split array to value
                    
                    % Extract first smp's timestamp and use it to zero out
                    % subsequent smp timestamps
                    if smp_count == 1; start_timestamp = str2double(value{2}); end
                    timestamp_zeroed = str2double(value{2}) - start_timestamp;
                    
                    % Triggers are defined when current_user_data tag differs from the
                    % previous
                    if ~strcmp(current_user_data,previous_user_data)
                        msg_count = msg_count + 1;
                        previous_user_data = current_user_data;
                        
                        % Update if current_user_data == one of the
                        % user-defined marker labels or the termination
                        % marker label
                        if strcmp(current_user_data,app.marker_labels{marker_count+1}) || strcmp(current_user_data,app.marker_labels{numel(app.marker_labels)})                                                      
                            % Perform Updates
                            marker_count = marker_count + 1;    %update marker
                            GUI_CommandWindowMessages(2,'AdditionalComponents',{app.marker_labels{marker_count},num2str(timestamp_zeroed)});
                            app = GUI_UpdateEventFields(app,marker_count,timestamp_zeroed,x,y_left,y_right);
                            
                            % Break while loop if current_user_data ==
                            % termination label
                            if strcmp(current_user_data, app.marker_labels{numel(app.marker_labels)})
                                GUI_CommandWindowMessages(3);
                                break
                            end
                        end                       
                    end

                    % Collect data
                    x = [x timestamp_zeroed];
                    y_left = [y_left str2double(dataSplit{10})];
                    y_right = [y_right str2double(dataSplit{20})];
                    
                    % Update plots
                    if timestamp_zeroed - last_timestamp_zeroed > app.plot_delay
                        update_logical_array = x > last_timestamp_zeroed;
                        app = GUI_UpdatePupilPlots(app, x(update_logical_array), y_left(update_logical_array), y_right(update_logical_array));
                        last_timestamp_zeroed = timestamp_zeroed;
                    end
                    
                    % Print to data file
                    fprintf(fileID,['SMP\t' repmat('%s\t',1,length(value)) '\n'],value{:});
                end
 
                Client2_WaitForData(app)        %Waits up to 10 seconds for input buffer to receive data
                pause(0.01);                    %Pause to avoid polling continuously
            end
            drawnow
            hold(app.PupilAxis,'off');
            
            % Reset ui component properties
            app.Event1_Lamp.Color = app.lamp_off_color;
            app.Event2_Lamp.Color = app.lamp_off_color;
            app.Event3_Lamp.Color = app.lamp_off_color;
            
            % Close file and stop data streaming          
            fclose(fileID);
            Client2_StopRecording(app.session2_client);

            % Update Pushbutton States
            app.StartExperimentButton.Enable = 'on';
            app.DisconnectButton.Enable = 'on';
            app.StopExperimentButton.Enable = 'off'; 
        end

        % Button pushed function: DisconnectButton
        function Disconnect(app, event)
            % Sends the message to disconnect session 1 client
            SendMsgToGP3(app.session2_client,'DISCONNECT');
            
            % Clean Up            
            CleanUpSocket(app.session2_client);
            fprintf('Disconnected: Socket successfully closed. You can now close this Matlab session.\n')
            
            % Update Pushbutton States
            app.ConnectButton.Enable = 'on';
            app.DisconnectButton.Enable = 'off';
            app.StartExperimentButton.Enable = 'off';
            app.StopExperimentButton.Enable = 'off';           
        end

        % Button pushed function: StopExperimentButton
        function StopExperiment(app, event)
            % Sends message to prematurely stop experiment
            SendMsgToGP3(app.session2_client,'STOP_EXPERIMENT');
            
            % Update Lamps
            app.Event1_Lamp.Color = app.lamp_off_color;
            app.Event2_Lamp.Color = app.lamp_off_color;
            app.Event3_Lamp.Color = app.lamp_off_color;
            
            % Update Pushbutton States
            app.StopExperimentButton.Enable = 'off';                                 
        end

        % Value changed function: Configuration_Button
        function ShowConfigOptions(app, event)
            value = app.Configuration_Button.Value;
            
            if value == 1
                app.Configuration_Panel.Visible = 1;    
            elseif value == 0
                app.Configuration_Panel.Visible = 0;    
            end
        end

        % Button pushed function: OutputFolderButton
        function SelectOutputFolder(app, event)
            % Select Output Folder and update OutputFolderEditField if
            % folder was selected          
            app = GUI_SelectOutputFolder(app);
        end

        % Button pushed function: RefreshButton
        function Refresh(app, event)
            % Checks the Stimuli/Sounds folder for changes when user
            % presses refresh button
            ReloadAudioFiles(app);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            okay_to_close = GUI_CloseFcnAlert(app); % Flag for whether it is okay to delete figure
            
            if okay_to_close
                delete(app)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Sounds Task';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.PupilAxis = uiaxes(app.UIFigure);
            xlabel(app.PupilAxis, 'Time (s)')
            ylabel(app.PupilAxis, 'Pupil Area')
            app.PupilAxis.XLim = [0 120];
            app.PupilAxis.YLim = [0 50];
            app.SubjectIDEditFieldLabel = uilabel(app.UIFigure);
            app.SubjectIDEditFieldLabel.Text = 'Subject ID:';
            app.SubjectIDEditField = uieditfield(app.UIFigure, 'text');
            app.SubjectIDEditField.Value = '0';
            app.ConnectButton = uibutton(app.UIFigure, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @SetUpConnections, true);
            app.ConnectButton.Text = 'Connect';
            app.StartExperimentButton = uibutton(app.UIFigure, 'push');
            app.StartExperimentButton.ButtonPushedFcn = createCallbackFcn(app, @StartExperiment, true);
            app.StartExperimentButton.Enable = 'off';
            app.StartExperimentButton.Text = 'Start Experiment';
            app.DisconnectButton = uibutton(app.UIFigure, 'push');
            app.DisconnectButton.ButtonPushedFcn = createCallbackFcn(app, @Disconnect, true);
            app.DisconnectButton.Enable = 'off';
            app.DisconnectButton.Text = 'Disconnect';
            app.StopExperimentButton = uibutton(app.UIFigure, 'push');
            app.StopExperimentButton.ButtonPushedFcn = createCallbackFcn(app, @StopExperiment, true);
            app.StopExperimentButton.Enable = 'off';
            app.StopExperimentButton.Text = 'Stop Experiment';
            app.Configuration_Button = uibutton(app.UIFigure, 'state');
            app.Configuration_Button.ValueChangedFcn = createCallbackFcn(app, @ShowConfigOptions, true);
            app.Configuration_Button.Text = 'Show Additional Configurations';
            app.Configuration_Panel = uipanel(app.UIFigure);
            app.Configuration_Panel.Visible = 'off';
            app.OutputFolderButton = uibutton(app.Configuration_Panel, 'push');
            app.OutputFolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelectOutputFolder, true);
            app.OutputFolderButton.Text = 'Output Folder';
            app.OutputFolderEditField = uieditfield(app.Configuration_Panel, 'text');
            app.OutputFolderEditField.Editable = 'off';
            app.BaselineEditFieldLabel_2 = uilabel(app.Configuration_Panel);
            app.BaselineEditFieldLabel_2.Text = 'Baseline';
            app.Event1_Dur_EditField = uieditfield(app.Configuration_Panel, 'numeric');
            app.Event1_Dur_EditField.Value = 20;
            app.PostAudioLabel_2 = uilabel(app.Configuration_Panel);
            app.PostAudioLabel_2.Text = 'Post-Audio';
            app.Event2_Dur_EditField = uieditfield(app.Configuration_Panel, 'numeric');
            app.Event2_Dur_EditField.Value = 20;
            app.DurationLabel = uilabel(app.Configuration_Panel);
            app.DurationLabel.Text = 'Duration:';
            app.EarButtonGroup = uibuttongroup(app.UIFigure);
            app.EarButtonGroup.Tooltip = {''};
            app.LeftEarButton = uitogglebutton(app.EarButtonGroup);
            app.LeftEarButton.Text = 'Left Ear';
            app.RightEarButton = uitogglebutton(app.EarButtonGroup);
            app.RightEarButton.Text = 'Right Ear';
            app.BothEarsButton = uitogglebutton(app.EarButtonGroup);
            app.BothEarsButton.Text = 'Both Ears';
            app.BothEarsButton.Value = true;
            app.AudioFileDropDownLabel = uilabel(app.UIFigure);
            app.AudioFileDropDownLabel.Text = 'Audio File';
            app.AudioFileDropDown = uidropdown(app.UIFigure);
            app.AudioFileDropDown.Items = {};
            app.AudioFileDropDown.Value = {};
            app.Event1_L_EditField = uieditfield(app.UIFigure, 'numeric');
            app.Event1_L_EditField.Tooltip = {''};
            app.Event2_L_EditField = uieditfield(app.UIFigure, 'numeric');
            app.Event2_L_EditField.Tooltip = {''};
            app.Event3_L_EditField = uieditfield(app.UIFigure, 'numeric');
            app.Event3_L_EditField.Tooltip = {''};
            app.Event1_R_EditField = uieditfield(app.UIFigure, 'numeric');
            app.Event1_R_EditField.Tooltip = {''};
            app.Event2_R_EditField = uieditfield(app.UIFigure, 'numeric');
            app.Event2_R_EditField.Tooltip = {''};
            app.Event3_R_EditField = uieditfield(app.UIFigure, 'numeric');
            app.Event3_R_EditField.Tooltip = {''};
            app.L_PupilLabel = uilabel(app.UIFigure);
            app.L_PupilLabel.Tooltip = {''};
            app.L_PupilLabel.Text = 'L Pupil:';
            app.R_PupilLabel = uilabel(app.UIFigure);
            app.R_PupilLabel.Tooltip = {''};
            app.R_PupilLabel.Text = 'R Pupil:';
            app.BaselineLampLabel = uilabel(app.UIFigure);
            app.BaselineLampLabel.Tooltip = {''};
            app.BaselineLampLabel.Text = 'Baseline';
            app.Event1_Lamp = uilamp(app.UIFigure);
            app.Event1_Lamp.Tooltip = {''};
            app.AudioLabel = uilabel(app.UIFigure);
            app.AudioLabel.Tooltip = {''};
            app.AudioLabel.Text = 'Audio';
            app.Event2_Lamp = uilamp(app.UIFigure);
            app.Event2_Lamp.Tooltip = {''};
            app.PostAudioLabel = uilabel(app.UIFigure);
            app.PostAudioLabel.Tooltip = {''};
            app.PostAudioLabel.Text = 'Post-Audio';
            app.Event3_Lamp = uilamp(app.UIFigure);
            app.Event3_Lamp.Tooltip = {''};
            app.RefreshButton = uibutton(app.UIFigure, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @Refresh, true);
            app.RefreshButton.Icon = 'reload.png';
            app.RefreshButton.Tooltip = {''};
            app.RefreshButton.Text = '';
            app.ExperimentNameEditFieldLabel = uilabel(app.UIFigure);
            app.ExperimentNameEditFieldLabel.Text = 'Experiment Name:';
            app.ExperimentNameEditField = uieditfield(app.UIFigure, 'text');
            app.ExperimentNameEditField.Value = 'Sounds';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Sounds_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end