classdef TwoTones_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        PupilAxis                     matlab.ui.control.UIAxes
        SubjectIDEditFieldLabel       matlab.ui.control.Label
        SubjectIDEditField            matlab.ui.control.EditField
        Event1Label_3                 matlab.ui.control.Label
        Event1_Lamp                   matlab.ui.control.Lamp
        Event2Label_3                 matlab.ui.control.Label
        Event2_Lamp                   matlab.ui.control.Lamp
        Event3Label                   matlab.ui.control.Label
        Event3_Lamp                   matlab.ui.control.Lamp
        ConnectButton                 matlab.ui.control.Button
        StartExperimentButton         matlab.ui.control.Button
        DisconnectButton              matlab.ui.control.Button
        Event1_L_EditField            matlab.ui.control.NumericEditField
        Event2_L_EditField            matlab.ui.control.NumericEditField
        Event3_L_EditField            matlab.ui.control.NumericEditField
        Event1_R_EditField            matlab.ui.control.NumericEditField
        Event2_R_EditField            matlab.ui.control.NumericEditField
        Event3_R_EditField            matlab.ui.control.NumericEditField
        L_PupilLabel                  matlab.ui.control.Label
        R_PupilLabel                  matlab.ui.control.Label
        StopExperimentButton          matlab.ui.control.Button
        Configuration_Button          matlab.ui.control.StateButton
        Configuration_Panel           matlab.ui.container.Panel
        OutputFolderButton            matlab.ui.control.Button
        OutputFolderEditField         matlab.ui.control.EditField
        Event1Label_4                 matlab.ui.control.Label
        Event1_Dur_EditField          matlab.ui.control.NumericEditField
        Event2Label_4                 matlab.ui.control.Label
        Event2_Dur_EditField          matlab.ui.control.NumericEditField
        Event3Label_2                 matlab.ui.control.Label
        Event3_Dur_EditField          matlab.ui.control.NumericEditField
        DurationLabel                 matlab.ui.control.Label
        ExperimentNameEditFieldLabel  matlab.ui.control.Label
        ExperimentNameEditField       matlab.ui.control.EditField
        EarButtonGroup                matlab.ui.container.ButtonGroup
        LeftEarButton                 matlab.ui.control.ToggleButton
        RightEarButton                matlab.ui.control.ToggleButton
        BothEarsButton                matlab.ui.control.ToggleButton
        ToneFrequencyHzSpinnerLabel   matlab.ui.control.Label
        ToneFrequencySpinner          matlab.ui.control.Spinner
        ToneDurationmsLabel           matlab.ui.control.Label
        ToneDurationSpinner           matlab.ui.control.Spinner
        ReturnButton                  matlab.ui.control.Button
    end


    properties (Access = public)        
        % These dummy properties are required to run commands here; change
        % directory to parent directory and add necessary paths before
        % creating elements
        dummy1 = cd(fileparts(mfilename('fullpath')))
        dummy2 = addpath('AppFiles');    
        parent_directory = pwd;     % Note: dummy1 ~= parent_directory (see cd output)
        
        % Marker properties
        marker
        marker_labels = {'EVENT1','EVENT2','EVENT3','STOP_RECORDING'};      % Make sure these matches the msg sent by the exp script
        marker_colors = {[0 0 0],[0.8 0.4 0.6],[0.4 0.8 0.8],[0 0 0]};
        marker_y_lower = 0;
        marker_y_upper = 50;
        
        % Lamp properties
        lamp_on_color = [0 0.8 0];
        lamp_off_color = [0.65 0.65 0.65];
        
        % Plot properties
        plot_delay = 0.1; % Delay (in seconds) before updating graphics
        left_pupil_plot
        right_pupil_plot
        
        % Other properties
        session2_client
        header
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Addpath to sub-dirs from the parent dir of this file
            addpath(genpath(fileparts(mfilename('fullpath'))));         
            
            % Initialize left and right pupil plots
            app = GUI_InitializePupilPlots(app);
            
            % Initialize marker structure (including the line handles and labels)
            app = GUI_InitializeMarkers(app);
            
            % Assign Tooltips
            app = GUI_AssignTooltips(app, {'SubjectIDEditField','SubjectIDEditFieldLabel',...
                'ExperimentNameEditField','ExperimentNameEditFieldLabel',...
                'OutputFolderButton','OutputFolderEditField',...
                'ReturnButton','EarButtonGroup','ReloadButton'});
        end

        % Button pushed function: ConnectButton
        function SetUpConnections(app, event)
            % Establish Connections Sequence
            app = GUI_EstablishConnectionsSequence(app, 'Experiment_TwoTones', 'ProgressDialog', 'on');
        end

        % Button pushed function: StartExperimentButton
        function StartExperiment(app, event)
            % Update Pushbutton States
            app.StartExperimentButton.Enable = 'off';
            app.DisconnectButton.Enable = 'off';
            app.StopExperimentButton.Enable = 'on'; 
                      
            % Retrieve user input from fields and store in data structure p
            p.Sub = app.SubjectIDEditField.Value;
            p.ToneFreq = num2str(app.ToneFrequencySpinner.Value);     
            p.ToneDur = num2str(app.ToneDurationSpinner.Value/1000);        %convert to s
            p.Event1Dur = num2str(app.Event1_Dur_EditField.Value);
            p.Event2Dur = num2str(app.Event2_Dur_EditField.Value);
            p.Event3Dur = num2str(app.Event3_Dur_EditField.Value);                                           
            p.Exp = app.ExperimentNameEditField.Value;
            p.Ear = upper(app.EarButtonGroup.SelectedObject.Text(1:end-4));     % 3 opts: LEFT, RIGHT, or BOTH
                     
            % Create file name and write header
            [app, outputFullPathName] = GUI_DefineOutputFullPathName(app,p,{'Sub','Ear','ToneFreq','Exp'});     % only includes these fields in filename            
            fileID = fopen(outputFullPathName,'w');
            fprintf(fileID,['DATATYPE\t' repmat('%s\t',1,length(app.header)) '\n'],app.header{:});
            
            % Sends message to session 1 client to start experiment
            start_msg_string_pairs = GUI_CreateStringPairs(p,fieldnames(p)'); % all fields included in the start message string
            start_message = ['START_' start_msg_string_pairs];
            SendMsgToGP3(app.session2_client, start_message);
            
            % Starts collecting data from GP3 server
            fprintf(app.session2_client, '<SET ID="ENABLE_SEND_DATA" STATE="1" />');
            pause(0.1);     % gives a chance for session2_client buffer to start receiving data
            
            % Reset plots and axis XLim
            hold(app.PupilAxis,'on');
            app = GUI_ClearPlots(app);
            app.PupilAxis.XLim(2) = sum([app.Event1_Dur_EditField.Value,app.Event2_Dur_EditField.Value,app.Event3_Dur_EditField.Value]);
            
            % Reset Fields that display pupil means
            [app.Event1_L_EditField.Value, app.Event2_L_EditField.Value, app.Event3_L_EditField.Value, ...
                app.Event1_R_EditField.Value, app.Event2_R_EditField.Value, app.Event3_R_EditField.Value] = deal(0);
            
            % Define counter variables and data containers
            previous_user_data = '';
            msg_count = 0;
            smp_count = 0;
            last_timestamp_zeroed = 0;
            marker_count = 0;
            x = [];
            y_left = [];
            y_right = [];
            
            % Wait for first marker label before proceeding
            fprintf('\n');  
            Client_WaitForMessage(app.session2_client, app.marker_labels{1}, 'TimeOutDuration', 10);
            GUI_CommandWindowMessages('ExperimentStarted');
                        
            timed_out_state = 0;
            while  timed_out_state == 0
                
                % Wait up to 10s for buffer to fill with data                
                timed_out_state = Client2_WaitForBufferToFill(app, 10);     % returns 1 if timed out before receiving data from buffer  
                if timed_out_state == 1; break; end                         % break out of loop if timed_out_state returned 1
                
                % Scan data from buffer and parse the xml format
                [dataSplit, current_user_data] = RetrieveDataFromBuffer(app.session2_client);

                if regexp(dataSplit{1},'<REC','once')
                    smp_count = smp_count+1;       
                    value = dataSplit(2:2:length(dataSplit));       % Saves every second element of the split array to value
                    
                    % Extract first smp's timestamp and use it to zero out
                    % subsequent smp timestamps
                    if smp_count == 1; start_timestamp = str2double(value{2}); end
                    timestamp_zeroed = str2double(value{2}) - start_timestamp;
                    
                    % Triggers are defined when current_user_data tag differs from the
                    % previous
                    if ~strcmp(current_user_data,previous_user_data)
                        msg_count = msg_count + 1;
                        previous_user_data = current_user_data;
                        
                        % Update if current_user_data == one of the
                        % user-defined marker labels or the termination
                        % marker label
                        if strcmp(current_user_data,app.marker_labels{marker_count+1}) || strcmp(current_user_data,app.marker_labels{numel(app.marker_labels)})                                                                                  
                            % Perform Updates
                            marker_count = marker_count + 1;    %update marker
                            GUI_CommandWindowMessages('ExperimentRunning','AdditionalComponents',{current_user_data,num2str(timestamp_zeroed)});
                            app = GUI_UpdateEventFields(app,marker_count,timestamp_zeroed,x,y_left,y_right);
                            
                            % Break while loop if current_user_data ==
                            % termination label
                            if strcmp(current_user_data, app.marker_labels{numel(app.marker_labels)})
                                GUI_CommandWindowMessages('ExperimentStopped');
                                [app.Event1_Lamp.Color, app.Event2_Lamp.Color, app.Event3_Lamp.Color] = deal(app.lamp_off_color);                                
                                break
                            end
                        end
                    end
                                      
                    % Collect data
                    x = [x timestamp_zeroed];
                    y_left = [y_left str2double(dataSplit{10})];
                    y_right = [y_right str2double(dataSplit{20})];
                    
                    % Update plots
                    if timestamp_zeroed - last_timestamp_zeroed > app.plot_delay
                        update_logical_array = x > last_timestamp_zeroed;
                        app = GUI_UpdatePupilPlots(app, x(update_logical_array), y_left(update_logical_array), y_right(update_logical_array));
                        last_timestamp_zeroed = timestamp_zeroed;
                    end
                    
                    % Print to data file
                    fprintf(fileID,['SMP\t' repmat('%s\t',1,length(value)) '\n'],value{:});
                end
                pause(0.01);                    %Pause to avoid polling continuously
            end
            drawnow
            hold(app.PupilAxis,'off');
            
                       
            % Close file and stop data streaming
            fclose(fileID);
            Client2_StopRecording(app.session2_client);

            % Update Pushbutton States
            app.StartExperimentButton.Enable = 'on';
            app.DisconnectButton.Enable = 'on';
            app.StopExperimentButton.Enable = 'off';
        end

        % Button pushed function: DisconnectButton
        function Disconnect(app, event)
            % Sends the message to disconnect session 1 client
            SendMsgToGP3(app.session2_client,'DISCONNECT');
            
            % Clean Up            
            CleanUpSocket(app.session2_client);
            GUI_CommandWindowMessages('GP3Disconnected');
            
            % Update Pushbutton States
            app.ConnectButton.Enable = 'on';
            app.DisconnectButton.Enable = 'off';
            app.StartExperimentButton.Enable = 'off';
            app.StopExperimentButton.Enable = 'off';     
        end

        % Button pushed function: StopExperimentButton
        function StopExperiment(app, event)
            % Sends message to prematurely stop experiment
            SendMsgToGP3(app.session2_client,'STOP_EXPERIMENT');
                       
            % Update Pushbutton States
            app.StopExperimentButton.Enable = 'off';             
        end

        % Value changed function: Configuration_Button
        function ShowConfigOptions(app, event)
            value = app.Configuration_Button.Value;
            
            if value == 1
                app.Configuration_Panel.Visible = 1;    
            elseif value == 0
                app.Configuration_Panel.Visible = 0;    
            end
        end

        % Button pushed function: OutputFolderButton
        function SelectOutputFolder(app, event)
            % Select Output Folder and update OutputFolderEditField if
            % folder was selected          
            app = GUI_SelectOutputFolder(app);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            okay_to_close = GUI_CloseFcnAlert(app); % Flag for whether it is okay to delete figure
            
            if okay_to_close
                delete(app)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Two Tones';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.PupilAxis = uiaxes(app.UIFigure);
            xlabel(app.PupilAxis, 'Time (s)')
            ylabel(app.PupilAxis, 'Pupil Area')
            app.PupilAxis.XLim = [0 120];
            app.PupilAxis.YLim = [0 50];
            app.SubjectIDEditFieldLabel = uilabel(app.UIFigure);
            app.SubjectIDEditFieldLabel.Text = 'Subject ID:';
            app.SubjectIDEditField = uieditfield(app.UIFigure, 'text');
            app.SubjectIDEditField.Value = '0';
            app.Event1Label_3 = uilabel(app.UIFigure);
            app.Event1Label_3.Text = 'Event 1';
            app.Event1_Lamp = uilamp(app.UIFigure);
            app.Event2Label_3 = uilabel(app.UIFigure);
            app.Event2Label_3.Text = 'Event 2';
            app.Event2_Lamp = uilamp(app.UIFigure);
            app.Event3Label = uilabel(app.UIFigure);
            app.Event3Label.Text = 'Event 3';
            app.Event3_Lamp = uilamp(app.UIFigure);
            app.ConnectButton = uibutton(app.UIFigure, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @SetUpConnections, true);
            app.ConnectButton.Text = 'Connect';
            app.StartExperimentButton = uibutton(app.UIFigure, 'push');
            app.StartExperimentButton.ButtonPushedFcn = createCallbackFcn(app, @StartExperiment, true);
            app.StartExperimentButton.Enable = 'off';
            app.StartExperimentButton.Text = 'Start Experiment';
            app.DisconnectButton = uibutton(app.UIFigure, 'push');
            app.DisconnectButton.ButtonPushedFcn = createCallbackFcn(app, @Disconnect, true);
            app.DisconnectButton.Enable = 'off';
            app.DisconnectButton.Text = 'Disconnect';
            app.Event1_L_EditField = uieditfield(app.UIFigure, 'numeric');
            app.Event2_L_EditField = uieditfield(app.UIFigure, 'numeric');
            app.Event3_L_EditField = uieditfield(app.UIFigure, 'numeric');
            app.Event1_R_EditField = uieditfield(app.UIFigure, 'numeric');
            app.Event2_R_EditField = uieditfield(app.UIFigure, 'numeric');
            app.Event3_R_EditField = uieditfield(app.UIFigure, 'numeric');
            app.L_PupilLabel = uilabel(app.UIFigure);
            app.L_PupilLabel.Text = 'L Pupil:';
            app.R_PupilLabel = uilabel(app.UIFigure);
            app.R_PupilLabel.Text = 'R Pupil:';
            app.StopExperimentButton = uibutton(app.UIFigure, 'push');
            app.StopExperimentButton.ButtonPushedFcn = createCallbackFcn(app, @StopExperiment, true);
            app.StopExperimentButton.Enable = 'off';
            app.StopExperimentButton.Text = 'Stop Experiment';
            app.Configuration_Button = uibutton(app.UIFigure, 'state');
            app.Configuration_Button.ValueChangedFcn = createCallbackFcn(app, @ShowConfigOptions, true);
            app.Configuration_Button.Text = 'Show Additional Configurations';
            app.Configuration_Panel = uipanel(app.UIFigure);
            app.Configuration_Panel.Visible = 'off';
            app.OutputFolderButton = uibutton(app.Configuration_Panel, 'push');
            app.OutputFolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelectOutputFolder, true);
            app.OutputFolderButton.Text = 'Output Folder';
            app.OutputFolderEditField = uieditfield(app.Configuration_Panel, 'text');
            app.OutputFolderEditField.Editable = 'off';
            app.Event1Label_4 = uilabel(app.Configuration_Panel);
            app.Event1Label_4.Text = 'Event 1';
            app.Event1_Dur_EditField = uieditfield(app.Configuration_Panel, 'numeric');
            app.Event1_Dur_EditField.Limits = [0 Inf];
            app.Event1_Dur_EditField.Value = 30;
            app.Event2Label_4 = uilabel(app.Configuration_Panel);
            app.Event2Label_4.Text = 'Event 2';
            app.Event2_Dur_EditField = uieditfield(app.Configuration_Panel, 'numeric');
            app.Event2_Dur_EditField.Limits = [0 Inf];
            app.Event2_Dur_EditField.Value = 18;
            app.Event3Label_2 = uilabel(app.Configuration_Panel);
            app.Event3Label_2.Text = 'Event 3';
            app.Event3_Dur_EditField = uieditfield(app.Configuration_Panel, 'numeric');
            app.Event3_Dur_EditField.Limits = [0 Inf];
            app.Event3_Dur_EditField.Value = 30;
            app.DurationLabel = uilabel(app.Configuration_Panel);
            app.DurationLabel.Text = 'Duration:';
            app.ExperimentNameEditFieldLabel = uilabel(app.UIFigure);
            app.ExperimentNameEditFieldLabel.Text = 'Experiment Name:';
            app.ExperimentNameEditField = uieditfield(app.UIFigure, 'text');
            app.ExperimentNameEditField.Value = 'TwoTones';
            app.EarButtonGroup = uibuttongroup(app.UIFigure);
            app.LeftEarButton = uitogglebutton(app.EarButtonGroup);
            app.LeftEarButton.Text = 'Left Ear';
            app.RightEarButton = uitogglebutton(app.EarButtonGroup);
            app.RightEarButton.Text = 'Right Ear';
            app.BothEarsButton = uitogglebutton(app.EarButtonGroup);
            app.BothEarsButton.Text = 'Both Ears';
            app.BothEarsButton.Value = true;
            app.ToneFrequencyHzSpinnerLabel = uilabel(app.UIFigure);
            app.ToneFrequencyHzSpinnerLabel.Text = 'Tone Frequency (Hz):';
            app.ToneFrequencySpinner = uispinner(app.UIFigure);
            app.ToneFrequencySpinner.Step = 100;
            app.ToneFrequencySpinner.Limits = [0 5000];
            app.ToneFrequencySpinner.RoundFractionalValues = 'on';
            app.ToneFrequencySpinner.Value = 1000;
            app.ToneDurationmsLabel = uilabel(app.UIFigure);
            app.ToneDurationmsLabel.Text = 'Tone Duration (ms):';
            app.ToneDurationSpinner = uispinner(app.UIFigure);
            app.ToneDurationSpinner.Step = 100;
            app.ToneDurationSpinner.Limits = [0 1000];
            app.ToneDurationSpinner.Value = 200;
            app.ReturnButton = uibutton(app.UIFigure, 'push');
            app.ReturnButton.Icon = 'arrow_back.png';
            app.ReturnButton.Tooltip = {''};
            app.ReturnButton.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TwoTones_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end